classdef ClonAnalysis_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ClonalysisVersion22LeonardoCamposColumbiaUniversity2024Label  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        AnalysisTab                    matlab.ui.container.Tab
        EditField                      matlab.ui.control.EditField
        EditFieldLabel                 matlab.ui.control.Label
        andEditField                   matlab.ui.control.EditField
        andEditFieldLabel              matlab.ui.control.Label
        EditField2                     matlab.ui.control.EditField
        RowofselectedpointLabel        matlab.ui.control.Label
        DistancebetweenEditField       matlab.ui.control.EditField
        DistancebetweenEditFieldLabel  matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        PolarPlotofCellsPanel          matlab.ui.container.Panel
        PlotButton                     matlab.ui.control.Button
        ExportButton                   matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        UIAxes2_2                      matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        PlottingOnlyTab                matlab.ui.container.Tab
        EditField_2                    matlab.ui.control.EditField
        EditField_2Label               matlab.ui.control.Label
        andEditField_2                 matlab.ui.control.EditField
        andEditField_2Label            matlab.ui.control.Label
        EditField2_2                   matlab.ui.control.EditField
        RowofselectedpointLabel_2      matlab.ui.control.Label
        DistancebetweenEditField_2     matlab.ui.control.EditField
        DistancebetweenEditField_2Label  matlab.ui.control.Label
        UITable_2                      matlab.ui.control.Table
        PolarPlotofCellsPanel_2        matlab.ui.container.Panel
        ExportButton_2                 matlab.ui.control.Button
        LoadButton_2                   matlab.ui.control.Button
        UIAxes2_4                      matlab.ui.control.UIAxes
        UIAxes2_3                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        FinalTable; % Description
        FinalTablePage2;
        PolarAxes; 
        PolarAxesPage2;% Description

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PolarAxes = polaraxes(app.PolarPlotofCellsPanel);
            title(app.UIAxes2, []);
            xlabel(app.UIAxes2, []);
            ylabel(app.UIAxes2, []);
            app.UIAxes2.XAxis.TickLabels = {};
            app.UIAxes2.YAxis.TickLabels = {};
            title(app.UIAxes2_2, []);
            xlabel(app.UIAxes2_2, []);
            ylabel(app.UIAxes2_2, []);
            app.UIAxes2_2.XAxis.TickLabels = {};
            app.UIAxes2_2.YAxis.TickLabels = {};

            app.PolarAxesPage2 = polaraxes(app.PolarPlotofCellsPanel_2);
            title(app.UIAxes2_3, []);
            xlabel(app.UIAxes2_3, []);
            ylabel(app.UIAxes2_3, []);
            app.UIAxes2_3.XAxis.TickLabels = {};
            app.UIAxes2_3.YAxis.TickLabels = {};
            title(app.UIAxes2_4, []);
            xlabel(app.UIAxes2_4, []);
            ylabel(app.UIAxes2_4, []);
            app.UIAxes2_4.XAxis.TickLabels = {};
            app.UIAxes2_4.YAxis.TickLabels = {};
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %% CHANGES ARE MADE on lines: 75,109-110,116,119,122
            %x = input("Enter directory for images:"); % Must be input as 'directory'
            x = uigetdir();
            
            %% Define function for reverse engineering missed markers
             function sol = SymsBypass(a, b, c)
                   newsolver = @(a,b,c)[a-1.056532571401189e+16.*c.*4.947194654278039e-17,b-1.056532571401189e+16.*c.*8.069079412562504e-17];
                   sol = newsolver(a,b,c);
             end
            %% back

            dlg = 1;
            while dlg == 1
            f = msgbox("Loading...","Please Wait","warn");
            cd(x)
            fileList = dir('*.png');
            C = struct2cell(fileList);
            Raws = find(contains(C(1,:),"Raw"));
            Ex = C;
            Ex(:,Raws) = [];

            for m = 1:length(Raws)
                
                Orig = imread(fileList(Raws(m)).name);
                BasicName = char(extractBetween(fileList(Raws(m)).name,"Sample"," Raw"));
                CorrespMark = find(contains(Ex(1,:),BasicName));
                
                Marker = imread(char(Ex(1,CorrespMark)));
                Mask = imsubtract(Marker,Orig);
                
                grayImage = rgb2gray(Mask);
            
                
                %Blob the markers
                % img = grayImage;
                % binimg=xor(bwareaopen(img,10),  bwareaopen(img,25)); %get pixels whose size are only betwen 7 and 12 (those points marked)
                % [Limg, num]=bwlabel(binimg); %Label each group of pixels
                % info=regionprops(Limg,'Area','PixelList','Centroid');
                % 
                % %Blob the numbers
                % binimg6=xor(bwareaopen(img,28),  bwareaopen(img,46)); %get pixels whose size are only betwen 7 and 12 (those points marked)
                % [Limg5, num5]=bwlabel(binimg6); %Label each group of pixels
                % info5=regionprops(Limg5,'Area','PixelList','Centroid','Orientation');
                % Labels = vertcat(info5.Centroid);

                %% New way
                img = grayImage;
                binimg = bwareaopen(img,round(0.01*min(size(img))));
                [Limg, num]=bwlabel(binimg); %Label each group of pixels
                info=regionprops(Limg,'Area','PixelList','Centroid','Orientation');
                Sortedinfo = sortrows(struct2table(info),1);
                gap = diff(Sortedinfo.Area);
                idx = find(gap > round(Sortedinfo.Area(1)*0.4));
                infotab = Sortedinfo(1:idx(1),:);
                info = table2struct(infotab);
                Numbers = Sortedinfo(1 + idx(1):end,:);
                
                [PotentialTypes, ~, ia] = unique(Numbers(:,[1;3]),'rows');
                tally = accumarray(ia,1);
                Types = find(tally > 1); %only keep ones that are repeated (area and orientation)
                info5 = table2struct(Numbers);
                Labels = vertcat(info5.Centroid);
                num = height(info);

                %% Back
                Orig_RGB = im2double(Orig);
                %Now iterate through all blobs and get RGB to get pixel
                clonal = zeros(1, num, 3, 'double');
                for i = 1:num
                    BlobID = info(i).PixelList;
                    Rpix = [];
                    Gpix = [];
                    Bpix = [];
                    for j = 1:length(BlobID)
                        Rpix(j) = Orig_RGB(BlobID(j,2),BlobID(j,1),1); %(Row (Y), Column (X), ColorValue)
                        Gpix(j) = Orig_RGB(BlobID(j,2),BlobID(j,1),2);
                        Bpix(j) = Orig_RGB(BlobID(j,2),BlobID(j,1),3);
                        %Now need to populate into matrix like rgb2 in example from other
                        %script so it can be plotted!
                    end
                    clonal(1,i,1) = mean(Rpix);
                    clonal(1,i,2) = mean(Gpix);
                    clonal(1,i,3) = mean(Bpix);
                end
                
                %Assign cell type and the corresponding shape
                CellType = strings(2,num);
                % for i = 1:num
                %     Pot = find(Labels(:,1) > info(i).Centroid(1));
                %     CloseX = find(6 < Labels(Pot,1)-info(i).Centroid(1) & Labels(Pot,1)-info(i).Centroid(1) < 7);
                %     CloseY = find(10 < Labels(Pot(CloseX),2)-info(i).Centroid(2) & Labels(Pot(CloseX),2)-info(i).Centroid(2) < 11);
                %     Closest = CloseX(CloseY);
                %     if isempty(Closest)
                %           CellType(1,i) = "Unknown"; 
                %           CellType(2,i) = "pentagram";
                %     else 
                %         if info5(Pot(Closest)).Area == 30 && round(info5(Pot(Closest)).Orientation,4) == -81.9675
                %             CellType(1,i) = "Ligamentocyte"; 
                %             CellType(2,i) = "^";
                %         elseif info5(Pot(Closest)).Area == 45 && round(info5(Pot(Closest)).Orientation,4) == 89.2620
                %             CellType(1,i) = "Chondrocyte";
                %             CellType(2,i) = "square";
                %         elseif info5(Pot(Closest)).Area == 42 && round(info5(Pot(Closest)).Orientation,4) == 83.5122
                %             CellType(1,i) = "Adipocyte"; 
                %             CellType(2,i) = "o";
                %         else 
                %             CellType(1,i) = "Unknown"; 
                %             CellType(2,i) = "pentagram";
                %         end 
                %     end
                % end 
                for i = 1:num
                    Pot = find(Labels(:,1) > info(i).Centroid(1) & Labels(:,2) > info(i).Centroid(2));
                    ClosestAng = find(0.5 < atan2(Labels(Pot,1)-info(i).Centroid(1),Labels(Pot,2)-info(i).Centroid(2)) & atan2(Labels(Pot,1)-info(i).Centroid(1), Labels(Pot,2)-info(i).Centroid(2)) < 0.6);
                    distances = pdist2(Numbers{Pot(ClosestAng),'Centroid'}, infotab{i,'Centroid'});
                    topthresh = log10(info(i).Area)*18.5;
                    %botthresh = log10(info(i).Area)*11;%threshold for distance 
                    ir = find(distances < topthresh);
                    %[val ir] = min(distances, [], "all");
                    
                    Closest = ClosestAng(ir);
                    if isempty(Closest)
                          CellType(1,i) = "Unknown"; 
                          CellType(2,i) = "pentagram";
                    elseif length(Closest) > 1
                          CellType(1,i) = "Unknown"; 
                          CellType(2,i) = "pentagram";
                    elseif ismember(Numbers{Pot(Closest),[1;3]},PotentialTypes{Types,:},'rows') == 0 % to make sure that this is a member of the repeated ones
                          CellType(1,i) = "Unknown"; 
                          CellType(2,i) = "pentagram"; 

                          if i == 1 
                            UsedMarkers = Pot(Closest);
                          else
                            UsedMarkers = [UsedMarkers, Pot(Closest)];
                          end
                    else 
                        if  abs(abs(info5(Pot(Closest)).Orientation)-89) < 0.5
                            CellType(1,i) = "Chondrocyte";
                            CellType(2,i) = "square";
                            
                        elseif abs(abs(info5(Pot(Closest)).Orientation)-83) < 0.6
                            CellType(1,i) = "Adipocyte"; 
                            CellType(2,i) = "o"; 
                        else 
                            CellType(1,i) = "Ligamentocyte"; 
                            CellType(2,i) = "^";
                        end

                        if i == 1 
                            UsedMarkers = Pot(Closest);
                        else
                            UsedMarkers = [UsedMarkers, Pot(Closest)];
                        end
                    end
                end
                
                %% In the cases where number found but not marker
                if height(info5) > length(UsedMarkers)
                   PotentialNumbers = [1:1:height(info5)];
                   Missing = find(ismember(PotentialNumbers, UsedMarkers) == false);
                   for i = 1:length(Missing)
                       distancetobe = log10(info(round(length(info)/2)).Area)*18.5; %use random marker from middle of list
            
                       %Solve system of equations to obtain coordinates of unseen
                       %marker centroid
                           %syms x y
                           %eqn1 = (x-info5(Missing(i)).Centroid(1))/(y-info5(Missing(i)).Centroid(2)) == tan(0.55);
                           %eqn2 = sqrt((info5(Missing(i)).Centroid(1)-x)^2 + (info5(Missing(i)).Centroid(2)-y)^2) == distancetobe;
                           %eqn3 = x < info5(Missing(i)).Centroid(1);
                           %sol = solve([eqn1,eqn2,eqn3],[x,y]);
                       %Attempt2
                           % sol = SymsWorkaround(info5,Missing,i,distancetobe);

                       sol = SymsBypass(info5(Missing(i)).Centroid(1),info5(Missing(i)).Centroid(2),distancetobe);

                       SquareLength = round(sqrt(info(round(length(info)/2)).Area));
                       if rem(SquareLength, 2) == 0 %if even
                           xmarkerrange = [(round(sol(1))-(SquareLength/2 - 1)):1:(round(sol(1))+(SquareLength/2))];
                           ymarkerrange = [(round(sol(2))-(SquareLength/2 - 1)):1:(round(sol(2))+(SquareLength/2))];
                       else 
                           xmarkerrange = [(round(sol(1))-round(SquareLength/2,TieBreaker="tozero")):1:(round(sol(1))+round(SquareLength/2,TieBreaker="tozero"))];
                           ymarkerrange = [(round(sol(2))-round(SquareLength/2,TieBreaker="tozero")):1:(round(sol(2))+round(SquareLength/2,TieBreaker="tozero"))];
                       end 
                       
                       for g = 1:SquareLength
                           BlobX = repelem(xmarkerrange(g),SquareLength)';
                           Newpixels = horzcat(BlobX,ymarkerrange');
            
                           if g == 1
                               BlobID = double(Newpixels);
                           else 
                               BlobID = vertcat(BlobID, double(Newpixels));
                           end
                       end 
            
                       %Make sure this new marker isn't actually already a different marker
                       new = 1;
                       for o = 1:num
                            MatchingLocations = find(ismember(BlobID, info(o).PixelList));
                            if length(MatchingLocations) > 0 
                               new = 0;
                            end 
                       end 
            
                       % create square around new centroid
                       % QA: make sure it doesn't overlap with any other cells
                       % if not then get the RGB and add to clonal
                       if new == 1
                           Rpix = [];
                           Gpix = [];
                           Bpix = [];
                           for j = 1:length(BlobID)
                               Rpix(j) = Orig_RGB(BlobID(j,2),BlobID(j,1),1); %(Row (Y), Column (X), ColorValue)
                               Gpix(j) = Orig_RGB(BlobID(j,2),BlobID(j,1),2);
                               Bpix(j) = Orig_RGB(BlobID(j,2),BlobID(j,1),3);
                               %Now need to populate into matrix like rgb2 in example from other
                               %script so it can be plotted!
                           end
                           newclone = length(clonal) + 1;
                           clonal(1,newclone,1) = mean(Rpix);
                           clonal(1,newclone,2) = mean(Gpix);
                           clonal(1,newclone,3) = mean(Bpix);
                           
                           CellType(1,newclone) = "Unknown";
                           CellType(2,newclone) = "pentagram";

                           info(newclone).Area = SquareLength^2;
                           info(newclone).Centroid = double([round(sol(1)) round(sol(2))]);
                           info(newclone).Orientation = 90;
                           info(newclone).PixelList = BlobID;
                       end
                   end 
                end 
                
                %% back 

                HSVRGB = rgb2hsv(clonal);
                HSVRGBOG = HSVRGB;
                
                HSVRGB(:,:,1) = HSVRGB(:,:,1)*360; %convert color wheel to degrees
                HSVRGB(:,:,2) = HSVRGB(:,:,2)*100; %convert saturation to percent
                
                HSVRGB2 = HSVRGB; %Have 2 of same vector (1 = degrees, 2 = radians)
                HSVRGB2(:,:,1) = HSVRGB2(:,:,1)*(pi/180); %convert degrees to radians
                
                %%%%%%%%%%%%%%%%%%%% Plot with angle dictating color
                j = size(HSVRGB2(:,:,1));
                Red = reshape(clonal(:,:,1),[prod(j),1]);
                Green = reshape(clonal(:,:,2),[prod(j),1]);
                Blue = reshape(clonal(:,:,3),[prod(j),1]);
                RGB = cat(2,Red,Green,Blue);
                Ang = HSVRGB2(:,:,1);
                Rad = HSVRGB2(:,:,2);
             
            
                %% One Large Table for Distance and Plotting
                varNames = ["Angle/Hue (Rad)","Radius/Saturation (%)","Cell Type","RGB","Blob Info"];
                TableNow = table(transpose(Ang),transpose(Rad),transpose(CellType),RGB,struct2table(info), 'VariableNames',varNames);
                TableNow.Image = repmat(string(Ex(1,CorrespMark)), height(TableNow), 1);
                if m == 1
                    FinalTable = TableNow;
                else 
                    FinalTable = vertcat(FinalTable, TableNow);
                end
            
            end 


            dlg = 2;
            end 
            %% Assign cell types for the ones that didn't automatically get assigned
            Unknowns = find(contains(FinalTable{:,"Cell Type"}(:,1),"Unknown"));
            for u = Unknowns'
                blobLoc = FinalTable{u,5}.Centroid;
                ThisImage = imread(FinalTable{u,6});
                I = imshow(ThisImage, 'parent', app.UIAxes2);
                app.UIAxes2.XLim = [0 I.XData(2)];
                app.UIAxes2.YLim = [0 I.YData(2)];
                hold(app.UIAxes2, 'on' )
                plot(app.UIAxes2, blobLoc(1),blobLoc(2), 'x', 'MarkerSize', 15, 'LineWidth', 2, 'MarkerEdgeColor','y') 
                title(app.UIAxes2,"");
                xlabel(app.UIAxes2,"");
                ylabel(app.UIAxes2,"");
                
                range = height(ThisImage)*(30/1024);
                
                borders = size(ThisImage);
                if blobLoc(1) - range < 0
                    rangexL = blobLoc(1)-1; %left range
                    rangexR = range;        %right range
                elseif blobLoc(1) + range > borders(1)
                    rangexL = range;
                    rangexR = borders(1)-blobLoc(1)-1;
                else
                    rangexL = range;
                    rangexR = range;
                end 

                if blobLoc(2) - range < 0
                    rangeyD = blobLoc(2)-1; %down range
                    rangeyU = range;        %up range
                elseif blobLoc(2) + range > borders(2)
                    rangeyD = range;
                    rangeyU = borders(2)-blobLoc(2)-1;
                else
                    rangeyD = range;   
                    rangeyU = range;
                end 

                CloserImage = ThisImage(round(blobLoc(2) - rangeyD):round(blobLoc(2) + rangeyU),round(blobLoc(1) - rangexL):round(blobLoc(1) + rangexR),:);
                I2 = imshow(CloserImage, 'parent', app.UIAxes2_2);
                app.UIAxes2_2.XLim = [0 I2.XData(2)];
                app.UIAxes2_2.YLim = [0 I2.YData(2)];
                hold(app.UIAxes2_2, 'on' )
                plot(app.UIAxes2_2, rangexL, rangeyD, 'x', 'MarkerSize', 15, 'LineWidth', 2, 'MarkerEdgeColor','y') 
                title(app.UIAxes2_2,"");
                xlabel(app.UIAxes2_2,"");
                ylabel(app.UIAxes2_2,"");

                %DialogBox
                NewCell = 0;
                while str2double(NewCell) ~= 1 &&  str2double(NewCell) ~= 2 && str2double(NewCell) ~= 3 && string(NewCell) ~= "NA"
                    prompt = {"Assign a cell type (1 = Ligament, 2 = Adipocytes, 3 = Chondrocytes). If this is an erroneous identification, please input NA:"};
                    dlgtitle = "NewCell";
                    definput = {'Unknown'};
                    opts.Interpreter = 'tex';
                    NewCell = inputdlg(prompt,dlgtitle,[1 40],definput,opts);
                end
                
                %Assign input
                if str2double(NewCell) == 1
                    FinalTable{u,"Cell Type"}(1) = "Ligamentocyte"; 
                    FinalTable{u,"Cell Type"}(2) = "^";
                elseif str2double(NewCell) == 2
                    FinalTable{u,"Cell Type"}(1) = "Adipocyte"; 
                    FinalTable{u,"Cell Type"}(2) = "o";
                elseif str2double(NewCell) == 3
                    FinalTable{u,"Cell Type"}(1) = "Chondrocyte";
                    FinalTable{u,"Cell Type"}(2) = "square"; 
                elseif string(NewCell) == "NA"
                    FinalTable(u,:) = [];
                end
            
                close
            end 
            

            dlg = 1;
            while dlg == 1
            f = msgbox("Loading...","Please Wait","warn");
            app.UITable.Data = {};
            app.FinalTable = FinalTable;
            app.UITable.Data = FinalTable;
            app.UITable.ColumnName = FinalTable.Properties.VariableNames;
            rownums = 1:height(FinalTable);
            app.UITable.RowName = num2cell(string(rownums))';
            dlg = 2;
            end 
            f = msgbox("Success");
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            for i= 1:height(app.FinalTable)
                p = polarscatter(app.PolarAxes, app.FinalTable{i,"Angle/Hue (Rad)"}, app.FinalTable{i,"Radius/Saturation (%)"}, [], app.FinalTable{i,"RGB"},'filled',app.FinalTable{i,"Cell Type"}(2),'ButtonDownFcn',{@blobCallbackFinal, app.FinalTable}); 
                p.SizeData = 70;
                if i == 1
                  hold(app.PolarAxes, 'on' )
                end
            end 
            
            hold(app.PolarAxes, 'off' )

            function blobCallbackFinal(~,point,table)
                coord = point.IntersectionPoint;
            
                AngMinus = abs(table{:,1} - coord(1));
                RadMinus = abs(table{:,2} - coord(2));
            
                index = find(AngMinus == min(AngMinus(:)) & RadMinus == min(RadMinus(:)));
                blobLoc = table{index,5}.Centroid;
                ThisImage = imread(table{index,6});

                I = imshow(ThisImage,'parent', app.UIAxes2);
                app.UIAxes2.XLim = [0 I.XData(2)];
                app.UIAxes2.YLim = [0 I.YData(2)];
                hold(app.UIAxes2, 'on' )
                plot(app.UIAxes2, blobLoc(1),blobLoc(2), 'x', 'MarkerSize', 15, 'LineWidth', 2, 'MarkerEdgeColor','y') 
                title(app.UIAxes2,"");
                xlabel(app.UIAxes2,"");
                ylabel(app.UIAxes2,"");

                range = round(height(ThisImage)*(30/1024));
                    
                borders = size(ThisImage);
                if blobLoc(1) - range < 0
                    rangexL = blobLoc(1)-1; %left range
                    rangexR = range;        %right range
                elseif blobLoc(1) + range > borders(1)
                    rangexL = range;
                    rangexR = borders(1)-blobLoc(1)-1;
                else
                    rangexL = range;
                    rangexR = range;
                end 

                if blobLoc(2) - range < 0
                    rangeyD = blobLoc(2)-1; %down range
                    rangeyU = range;        %up range
                elseif blobLoc(2) + range > borders(2)
                    rangeyD = range;
                    rangeyU = borders(2)-blobLoc(2)-1;
                else
                    rangeyD = range;   
                    rangeyU = range;
                end 

                CloserImage = ThisImage(round(blobLoc(2) - rangeyD):round(blobLoc(2) + rangeyU),round(blobLoc(1) - rangexL):round(blobLoc(1) + rangexR),:);
                I2 = imshow(CloserImage, 'parent', app.UIAxes2_2);
                app.UIAxes2_2.XLim = [0 I2.XData(2)];
                app.UIAxes2_2.YLim = [0 I2.YData(2)];
                hold(app.UIAxes2_2, 'on' )
                plot(app.UIAxes2_2, rangexL, rangeyD, 'x', 'MarkerSize', 15, 'LineWidth', 2, 'MarkerEdgeColor','y') 
                title(app.UIAxes2_2,"");
                xlabel(app.UIAxes2_2,"");
                ylabel(app.UIAxes2_2,"");
                
                %% Find distance between 2 subsequently clicked points
                persistent index2
                app.EditField2.Value = string(index);
                if isempty(index2)
                else
                    dpolar = sqrt((table{index,2})^2 + (table{index2,2})^2 - 2*(table{index,2})*(table{index2,2})*cos((table{index,1})-(table{index2,1})));
                    
                    app.andEditField.Value = string(index2);
                    app.DistancebetweenEditField.Value = string(index);
                    app.EditField.Value = string(dpolar);
                end
                index2 = index;
            end 
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            dlg = 1;
            while dlg == 1
            f = msgbox("Export in Progress...","Please Wait","warn");
            sz = [(height(app.FinalTable)*(height(app.FinalTable)-1)/2) 15];
            varTypes = ["string","double","double","double","double","string","string","double","double","double","double","string","string","string","double"];
            varNames = ["Comparison","Angle1_(rad)","Radius1_(sat)","XCoord_1","YCoord_1","Image1","CellType1","Angle2_(rad)","Radius2_(sat)","XCoord_2","YCoord_2","Image2","CellType2","Same Cell Type","Distance"];
            temps = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            n = 1;
            for i = 1:(height(app.FinalTable)-1) %parse through each point and determine distance
                for k = (i+1):height(app.FinalTable)
                    dpolar = sqrt((app.FinalTable{i,2})^2 + (app.FinalTable{k,2})^2 - 2*(app.FinalTable{i,2})*(app.FinalTable{k,2})*cos((app.FinalTable{i,1})-(app.FinalTable{k,1})));
                    str = i + " vs. " + k;
                    temps(n,1) = {str};
            
                    temps(n,2) = {app.FinalTable{i,1}};
                    temps(n,3) = {app.FinalTable{i,2}};
                    temps(n,4) = {app.FinalTable{i,5}.Centroid(1)};
                    temps(n,5) = {app.FinalTable{i,5}.Centroid(2)};
                    temps(n,6) = {app.FinalTable{i,6}};
                    temps(n,7) = {app.FinalTable{i,3}(1)};
            
                    temps(n,8) = {app.FinalTable{k,1}};
                    temps(n,9) = {app.FinalTable{k,2}};
                    temps(n,10) = {app.FinalTable{k,5}.Centroid(1)};
                    temps(n,11) = {app.FinalTable{k,5}.Centroid(2)};
                    temps(n,12) = {app.FinalTable{k,6}};
                    temps(n,13) = {app.FinalTable{k,3}(1)};
            
                    if app.FinalTable{i,3}(1) == app.FinalTable{k,3}(1)
                        temps(n,14) = {"Yes"};
                    else 
                        temps(n,14) = {"No"};
                    end 
                    
                    temps(n,15) = {dpolar};
                    n = n + 1;
                end
            end
            

            filename = 'celldata.xlsx';
            FinalTable2 = splitvars(app.FinalTable,"Blob Info");
            writetable(FinalTable2,filename)
            filename = 'celldistance.xlsx';
            writetable(temps,filename)

            %exportgraphics(app.PolarAxes,'polarcells.mat')
            exportgraphics(app.PolarAxes,'polarcells.png')
            dlg = 2;
            end 
            %show it works
            f = msgbox("Export Successful");

        end

        % Callback function
        function UITableDisplayDataChanged(app, event)

            
        end

        % Value changed function: DistancebetweenEditField
        function DistancebetweenEditFieldValueChanged(app, event)
            value = app.DistancebetweenEditField.Value;
            
        end

        % Value changed function: EditField2
        function EditField2ValueChanged(app, event)
            value = app.EditField2.Value;
            
        end

        % Value changing function: EditField2
        function EditField2ValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: andEditField
        function andEditFieldValueChanged(app, event)
            value = app.andEditField.Value;
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            x = uigetdir();
            dlg = 1;
            while dlg == 1
            f = msgbox("Loading...","Please Wait","warn");
            cd(x)
            tables = dir('celldata.xlsx');
            Table = readtable(tables.name);
            
            
            app.UITable_2.Data = {};
            app.FinalTablePage2 = Table;
            app.UITable_2.Data = Table;
            app.UITable_2.ColumnName = Table.Properties.VariableNames;
            rownums = 1:height(Table);
            app.UITable_2.RowName = num2cell(string(rownums))';
            dlg = 2;
            end 
            f = msgbox("Load Successful");


            %% Plot
            for i= 1:height(app.FinalTablePage2)
                p = polarscatter(app.PolarAxesPage2, app.FinalTablePage2{i,1}, app.FinalTablePage2{i,2}, [], app.FinalTablePage2{i,5:7},'filled',char(app.FinalTablePage2{i,"CellType_2"}),'ButtonDownFcn',{@blobCallbackAlone, app.FinalTablePage2}); 
                %p = polarscatter(, app.FinalTablePage2{i,"Angle/Hue (Rad)"}, app.FinalTablePage2{i,"Radius/Saturation (%)"}, [], app.FinalTablePage2{i,"RGB"},'filled',app.FinalTablePage2{i,"Cell Type"}(2),'ButtonDownFcn',{@blobCallbackFinal, app.FinalTablePage2}); 
                p.SizeData = 70;
                if i == 1
                  hold(app.PolarAxesPage2, 'on' )
                end
            end 

            hold(app.PolarAxesPage2, 'off' )
            function blobCallbackAlone(~,point,table)
                coord = point.IntersectionPoint;
            
                AngMinus = abs(table{:,1} - coord(1));
                RadMinus = abs(table{:,2} - coord(2));
            
                index = find(AngMinus == min(AngMinus(:)) & RadMinus == min(RadMinus(:)));
                blobLoc1 = table{index,"Centroid_1"};
                blobLoc2 = table{index,"Centroid_2"};
                ThisImage = imread(char(table{index,"Image"}));

                I = imshow(ThisImage,'parent', app.UIAxes2_3);
                app.UIAxes2_3.XLim = [0 I.XData(2)];
                app.UIAxes2_3.YLim = [0 I.YData(2)];
                hold(app.UIAxes2_3, 'on' )
                plot(app.UIAxes2_3, blobLoc1,blobLoc2, 'x', 'MarkerSize', 15, 'LineWidth', 2, 'MarkerEdgeColor','y') 
                title(app.UIAxes2_3,"");
                xlabel(app.UIAxes2_3,"");
                ylabel(app.UIAxes2_3,"");

                range = round(height(ThisImage)*(30/1024));
                borders = size(ThisImage);
                if blobLoc1 - range < 0
                    rangexL = blobLoc1-1; %left range
                    rangexR = range;        %right range
                elseif blobLoc1 + range > borders(1)
                    rangexL = range;
                    rangexR = borders(1)-blobLoc1-1;
                else
                    rangexL = range;
                    rangexR = range;
                end 

                if blobLoc2 - range < 0
                    rangeyD = blobLoc2-1; %down range
                    rangeyU = range;        %up range
                elseif blobLoc2 + range > borders(2)
                    rangeyD = range;
                    rangeyU = borders(2)-blobLoc2-1;
                else
                    rangeyD = range;   
                    rangeyU = range;
                end 

                CloserImage = ThisImage(round(blobLoc2 - rangeyD):round(blobLoc2 + rangeyU),round(blobLoc1 - rangexL):round(blobLoc1 + rangexR),:);
                I2 = imshow(CloserImage, 'parent', app.UIAxes2_4);
                app.UIAxes2_4.XLim = [0 I2.XData(2)];
                app.UIAxes2_4.YLim = [0 I2.YData(2)];
                hold(app.UIAxes2_4, 'on' )
                plot(app.UIAxes2_4, rangexL, rangeyD, 'x', 'MarkerSize', 15, 'LineWidth', 2, 'MarkerEdgeColor','y') 
                title(app.UIAxes2_4,"");
                xlabel(app.UIAxes2_4,"");
                ylabel(app.UIAxes2_4,"");
                
                %% Find distance between 2 subsequently clicked points
                persistent index3
                app.EditField2_2.Value = string(index);
                if isempty(index3)
                else
                    dpolar = sqrt((table{index,2})^2 + (table{index3,2})^2 - 2*(table{index,2})*(table{index3,2})*cos((table{index,1})-(table{index3,1})));
                    
                    app.andEditField_2.Value = string(index3);
                    app.DistancebetweenEditField_2.Value = string(index);
                    app.EditField_2.Value = string(dpolar);
                end
                index3 = index;
            end 
        end

        % Button pushed function: ExportButton_2
        function ExportButton_2Pushed(app, event)
            exportgraphics(app.PolarAxesPage2,'polarcells.png')
        end

        % Value changed function: EditField2_2
        function EditField2_2ValueChanged(app, event)
            value = app.EditField2_2.Value;
            
        end

        % Value changed function: DistancebetweenEditField_2
        function DistancebetweenEditField_2ValueChanged(app, event)
            value = app.DistancebetweenEditField_2.Value;
            
        end

        % Value changed function: andEditField_2
        function andEditField_2ValueChanged(app, event)
            value = app.andEditField_2.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.UIAxes2 = uiaxes(app.AnalysisTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.UIAxes2_2 = uiaxes(app.AnalysisTab);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.Visible = 'off';
            app.LoadButton = uibutton(app.AnalysisTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ExportButton = uibutton(app.AnalysisTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.PlotButton = uibutton(app.AnalysisTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PolarPlotofCellsPanel = uipanel(app.AnalysisTab);
            app.PolarPlotofCellsPanel.Title = 'Polar Plot of Cells';
            app.UITable = uitable(app.AnalysisTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.DistancebetweenEditFieldLabel = uilabel(app.AnalysisTab);
            app.DistancebetweenEditFieldLabel.Text = 'Distance between ';
            app.DistancebetweenEditField = uieditfield(app.AnalysisTab, 'text');
            app.DistancebetweenEditField.ValueChangedFcn = createCallbackFcn(app, @DistancebetweenEditFieldValueChanged, true);
            app.RowofselectedpointLabel = uilabel(app.AnalysisTab);
            app.RowofselectedpointLabel.Text = 'Row of selected point:';
            app.EditField2 = uieditfield(app.AnalysisTab, 'text');
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.EditField2.ValueChangingFcn = createCallbackFcn(app, @EditField2ValueChanging, true);
            app.andEditFieldLabel = uilabel(app.AnalysisTab);
            app.andEditFieldLabel.Text = 'and';
            app.andEditField = uieditfield(app.AnalysisTab, 'text');
            app.andEditField.ValueChangedFcn = createCallbackFcn(app, @andEditFieldValueChanged, true);
            app.EditFieldLabel = uilabel(app.AnalysisTab);
            app.EditFieldLabel.Text = '=';
            app.EditField = uieditfield(app.AnalysisTab, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.PlottingOnlyTab = uitab(app.TabGroup);
            app.PlottingOnlyTab.Title = 'Plotting Only';
            app.UIAxes2_3 = uiaxes(app.PlottingOnlyTab);
            title(app.UIAxes2_3, 'Title')
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes2_3.Visible = 'off';
            app.UIAxes2_4 = uiaxes(app.PlottingOnlyTab);
            title(app.UIAxes2_4, 'Title')
            xlabel(app.UIAxes2_4, 'X')
            ylabel(app.UIAxes2_4, 'Y')
            zlabel(app.UIAxes2_4, 'Z')
            app.UIAxes2_4.Visible = 'off';
            app.LoadButton_2 = uibutton(app.PlottingOnlyTab, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.ExportButton_2 = uibutton(app.PlottingOnlyTab, 'push');
            app.ExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_2Pushed, true);
            app.ExportButton_2.Text = 'Export';
            app.PolarPlotofCellsPanel_2 = uipanel(app.PlottingOnlyTab);
            app.PolarPlotofCellsPanel_2.Title = 'Polar Plot of Cells';
            app.UITable_2 = uitable(app.PlottingOnlyTab);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.DistancebetweenEditField_2Label = uilabel(app.PlottingOnlyTab);
            app.DistancebetweenEditField_2Label.Text = 'Distance between ';
            app.DistancebetweenEditField_2 = uieditfield(app.PlottingOnlyTab, 'text');
            app.DistancebetweenEditField_2.ValueChangedFcn = createCallbackFcn(app, @DistancebetweenEditField_2ValueChanged, true);
            app.RowofselectedpointLabel_2 = uilabel(app.PlottingOnlyTab);
            app.RowofselectedpointLabel_2.Text = 'Row of selected point:';
            app.EditField2_2 = uieditfield(app.PlottingOnlyTab, 'text');
            app.EditField2_2.ValueChangedFcn = createCallbackFcn(app, @EditField2_2ValueChanged, true);
            app.andEditField_2Label = uilabel(app.PlottingOnlyTab);
            app.andEditField_2Label.Text = 'and';
            app.andEditField_2 = uieditfield(app.PlottingOnlyTab, 'text');
            app.andEditField_2.ValueChangedFcn = createCallbackFcn(app, @andEditField_2ValueChanged, true);
            app.EditField_2Label = uilabel(app.PlottingOnlyTab);
            app.EditField_2Label.Text = '=';
            app.EditField_2 = uieditfield(app.PlottingOnlyTab, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.ClonalysisVersion22LeonardoCamposColumbiaUniversity2024Label = uilabel(app.UIFigure);
            app.ClonalysisVersion22LeonardoCamposColumbiaUniversity2024Label.Text = 'Clonalysis Version: 2.2, Leonardo Campos, Columbia University, 2024';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClonAnalysis_Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end